classdef DAR < matlab.ui.componentcontainer.ComponentContainer
    %DAR A single lab procedure status and data
    %   This component is used in the Lab Procedure app to track and
    %   update data associated with a procedure

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout2             matlab.ui.container.GridLayout
        Panel                   matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        AnlisisdedatosLabel     matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        CargadedatosTab         matlab.ui.container.Tab
        GridLayout3             matlab.ui.container.GridLayout
        RealizaranlisisButton   matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        ImportDataButton        matlab.ui.control.Button
        MtododeanlisisLabel     matlab.ui.control.Label
        StatusDropDown          matlab.ui.control.DropDown
        VisualizacindedatosTab  matlab.ui.container.Tab
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        DateChanged
        StatusChanged
    end

    properties (Access = private)
        DataFilename % Path to data file
    end
    
    properties (Access = public)
        Date (1,1) datetime = datetime('today'); % Lab procedure date
        Status (1,1) string {mustBeMember(Status, ["PCA", "LDA", "CDA"])} = "PCA"; % Lab procedure method
        Title (1,1) string {mustBeTextScalar(Title)} = "Lab Procedure 1"; % Lab procedure title
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function StatusDropDownValueChanged(comp, event)
            % Update public property and trigger event
            comp.Status = comp.StatusDropDown.Value;
            notify(comp,"StatusChanged")
        end

        % Callback function: not associated with a component
        function DatePickerValueChanged(comp, event)
            % Update public property and trigger event
            
            notify(comp,"DateChanged");
        end

        % Callback function: not associated with a component
        function ViewDataButtonPushed(comp, event)
            open(comp.DataFilename);
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(comp, event)
            [f,p] = uigetfile({'*.xlsx'},'File Selector');

            % Store data
            if f ~= 0
                comp.DataFilename = fullfile(p,f);
                disp(comp.DataFilename);                
                t = readtable(comp.DataFilename,"Sheet",1);
                comp.UITable.Data = t;
                comp.UITable.ColumnName = t.Properties.VariableNames;                
                %Activación de los botones 
                comp.ViewDataButton.Enable = "on";
                comp.MtododeanlisisLabel = "on";
                comp.StatusDropDown = "on";
                comp.RealizaranlisisButton = "on";

            end
        end

        % Callback function: not associated with a component
        function ImportDataButtonPushed2(comp, event)
           
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Update underlying components
            comp.AnlisisdedatosLabel.Text = comp.Title;
            comp.StatusDropDown.Value = comp.Status;
            comp.GridLayout.BackgroundColor = comp.BackgroundColor;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 644 602];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout2 = uigridlayout(comp);
            comp.Panel = uipanel(comp.GridLayout2);
            comp.GridLayout = uigridlayout(comp.Panel);
            comp.TabGroup = uitabgroup(comp.GridLayout);
            comp.CargadedatosTab = uitab(comp.TabGroup);
            comp.CargadedatosTab.Title = 'Carga de datos';
            comp.GridLayout3 = uigridlayout(comp.CargadedatosTab);
            comp.StatusDropDown = uidropdown(comp.GridLayout3);
            comp.StatusDropDown.Items = {'PCA', 'LDA', 'CDA'};
            comp.StatusDropDown.Enable = 'off';
            comp.StatusDropDown.Value = 'PCA';
            comp.MtododeanlisisLabel = uilabel(comp.GridLayout3);
            comp.MtododeanlisisLabel.Enable = 'off';
            comp.MtododeanlisisLabel.Text = 'Método de análisis';
            comp.ImportDataButton = uibutton(comp.GridLayout3, 'push');
            comp.ImportDataButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ImportDataButtonPushed, true);
            comp.ImportDataButton.Text = 'Import Data';
            comp.UITable = uitable(comp.GridLayout3);
            comp.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            comp.UITable.RowName = {};
            comp.RealizaranlisisButton = uibutton(comp.GridLayout3, 'push');
            comp.RealizaranlisisButton.Enable = 'off';
            comp.RealizaranlisisButton.Text = 'Realizar análisis';
            comp.VisualizacindedatosTab = uitab(comp.TabGroup);
            comp.VisualizacindedatosTab.Title = 'Visualización de datos';
            comp.AnlisisdedatosLabel = uilabel(comp.GridLayout);
            comp.AnlisisdedatosLabel.Text = 'Análisis de datos';
        end
    end
end